classdef chirp_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EditField_2               matlab.ui.control.NumericEditField
        EditField                 matlab.ui.control.NumericEditField
        ButtonGroup               matlab.ui.container.ButtonGroup
        SoundButton               matlab.ui.control.RadioButton
        SpectrogramButton         matlab.ui.control.RadioButton
        FrequencyevolutionButton  matlab.ui.control.RadioButton
        WaveformButton            matlab.ui.control.RadioButton
        AnimationButton           matlab.ui.control.RadioButton
        Mass2Slider               matlab.ui.control.Slider
        Mass2SliderLabel          matlab.ui.control.Label
        GOButton                  matlab.ui.control.Button
        Mass1Slider               matlab.ui.control.Slider
        Mass1SliderLabel          matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Mass1 % Mass of star 1 (solar mass units)
        Mass2 % Mass of star 2 (solar mass units)
        RunMode % Mode of simulation:
        % 1 = animation
        % 2 = waveform
        % 3 = frequency vs time
        % 4 = spectrogram
        % 5 = sound
        MassMin = 0.25;
        MassMax = 100.;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Mass1 = 10.;
            app.Mass2 = 10.;
            app.RunMode = 2;
            app.ButtonGroup.SelectedObject = app.WaveformButton;
            app.EditField.Value = app.Mass1;
            app.EditField_2.Value = app.Mass2;
            status = run_chirp(app.UIAxes,app.Mass1,app.Mass2,app.RunMode);
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            status = run_chirp(app.UIAxes,app.Mass1,app.Mass2,app.RunMode);
        end

        % Value changed function: Mass1Slider
        function Mass1SliderValueChanged(app, event)
            value = app.Mass1Slider.Value;
            app.Mass1 = value;
            app.EditField.Value = value;
        end

        % Value changed function: Mass2Slider
        function Mass2SliderValueChanged(app, event)
            value = app.Mass2Slider.Value;
            app.Mass2 = value;
            app.EditField_2.Value = value;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if (selectedButton==app.AnimationButton)
                app.RunMode = 1;
            elseif (selectedButton==app.WaveformButton)   
                app.RunMode = 2;
            elseif (selectedButton==app.FrequencyevolutionButton)
                app.RunMode = 3;
            elseif (selectedButton==app.SpectrogramButton)
                app.RunMode = 4;
            elseif (selectedButton==app.SoundButton)
                app.RunMode = 5;
            end
            status = run_chirp(app.UIAxes,app.Mass1,app.Mass2,app.RunMode);
        end

        % Button down function: ButtonGroup
        function ButtonGroupButtonDown(app, event)
            status = run_chirp(app.UIAxes,app.Mass1,app.Mass2,app.RunMode);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if (value<app.MassMin)
                value = app.MassMin;
            elseif (value>app.MassMax)
                value = app.MassMax;
            end
            app.EditField.Value = value;
            app.Mass1Slider.Value = value;
            app.Mass1 = value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            if (value<app.MassMin)
                value = app.MassMin;
            elseif (value>app.MassMax)
                value = app.MassMax;
            end
            app.EditField_2.Value = value;
            app.Mass2Slider.Value = value;
            app.Mass2 = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Mass1SliderLabel = uilabel(app.UIFigure);
            app.Mass1SliderLabel.Text = 'Mass 1';
            app.Mass1Slider = uislider(app.UIFigure);
            app.Mass1Slider.Limits = [0.1 100];
            app.Mass1Slider.MajorTickLabels = {'0.5', '20.', '40.', '60.', '80.', '100'};
            app.Mass1Slider.ValueChangedFcn = createCallbackFcn(app, @Mass1SliderValueChanged, true);
            app.Mass1Slider.Value = 10;
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.Mass2SliderLabel = uilabel(app.UIFigure);
            app.Mass2SliderLabel.Text = 'Mass 2';
            app.Mass2Slider = uislider(app.UIFigure);
            app.Mass2Slider.Limits = [0.1 100];
            app.Mass2Slider.MajorTickLabels = {'0.5', '20.', '40.', '60.', '80.', '100'};
            app.Mass2Slider.ValueChangedFcn = createCallbackFcn(app, @Mass2SliderValueChanged, true);
            app.Mass2Slider.Value = 10;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ButtonGroupButtonDown, true);
            app.AnimationButton = uiradiobutton(app.ButtonGroup);
            app.AnimationButton.Text = ' Animation';
            app.AnimationButton.Value = true;
            app.WaveformButton = uiradiobutton(app.ButtonGroup);
            app.WaveformButton.Text = ' Waveform';
            app.FrequencyevolutionButton = uiradiobutton(app.ButtonGroup);
            app.FrequencyevolutionButton.Text = ' Frequency evolution';
            app.SpectrogramButton = uiradiobutton(app.ButtonGroup);
            app.SpectrogramButton.Text = ' Spectrogram';
            app.SoundButton = uiradiobutton(app.ButtonGroup);
            app.SoundButton.Text = ' Sound';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueDisplayFormat = '%5.1f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 90;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%5.1f';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 90;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chirp_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end